classdef articulated_arm_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ZTextArea                   matlab.ui.control.TextArea
        ZTextAreaLabel              matlab.ui.control.Label
        YTextArea                   matlab.ui.control.TextArea
        YTextAreaLabel              matlab.ui.control.Label
        XTextArea                   matlab.ui.control.TextArea
        XTextAreaLabel              matlab.ui.control.Label
        PositionofEndEffectorLabel  matlab.ui.control.Label
        theta3TextArea              matlab.ui.control.TextArea
        theta3TextAreaLabel         matlab.ui.control.Label
        theta2TextArea              matlab.ui.control.TextArea
        theta2TextAreaLabel         matlab.ui.control.Label
        theta1TextArea              matlab.ui.control.TextArea
        theta1TextAreaLabel         matlab.ui.control.Label
        PauseButton                 matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        Theta3Slider                matlab.ui.control.Slider
        Theta3SliderLabel           matlab.ui.control.Label
        Theta2Slider                matlab.ui.control.Slider
        Theta2SliderLabel           matlab.ui.control.Label
        Theta1Slider                matlab.ui.control.Slider
        Theta1SliderLabel           matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    %% Properties
    properties (Access = private)
        theta1 = 0; % Init theta 1
        theta2 = 0; % Init theta 2
        theta3 = 0; % Init theta 3
        pause_status = false;

        oldtheta1 = 0; % Init oldtheta 1
        oldtheta2 = 0; % Init oldtheta 2
        oldtheta3 = 0; % Init oldtheta 3
        
        % Radius of Cylinder
        R_b = 10; % Radius of base
        R_a = 7; % Radius of arm
        R_l = 3; % Radius of link
        
        % Height of Cylinder
        H_b = 10; % Height of base
        H_a = 10; % Height of arm
        H_l = 20; % Height of link

    end
    
    methods (Access = private)
        %% Update theta function
        function UpdateAndVizualize(app)
            if app.theta1 ~= app.oldtheta1
                theta1_chain = linspace(app.oldtheta1, app.theta1, 30);
                k = 1;
                while (k ~= 30) && (app.pause_status == false)
                    app.Executable(theta1_chain(k), app.oldtheta2, app.oldtheta3)
                    k = k + 1;
                    pause(0.1);
                end
                app.oldtheta1 = theta1_chain(k);
            end
            if app.theta2 ~= app.oldtheta2
                theta2_chain = linspace(app.oldtheta2, app.theta2, 30);
                k = 1;
                while (k ~= 30) && (app.pause_status == false)
                    app.Executable(app.oldtheta1, theta2_chain(k), app.oldtheta3)
                    k = k + 1;
                    pause(0.1);
                end
                app.oldtheta2 = theta2_chain(k);
            end
            if app.theta3 ~= app.oldtheta3
                theta3_chain = linspace(app.oldtheta3, app.theta3, 30);
                k = 1;
                while (k ~= 30) && (app.pause_status == false)
                    app.Executable(app.oldtheta1, app.oldtheta2,theta3_chain(k))
                    k = k + 1;
                    pause(0.1);
                end
                app.oldtheta3 = theta3_chain(k);
            end
        end
        
        
        %% Executable function
        function Executable(app, theta1, theta2, theta3)
            [x_b, y_b, z_b] = cylinder(app.R_b);
            [x_a1, y_a1, z_a1] = cylinder(app.R_a);
            [x_a2, y_a2, z_a2] = cylinder(app.R_a);
            [x_l1, y_l1, z_l1] = cylinder(app.R_l);
            [x_l2, y_l2, z_l2] = cylinder(app.R_l);
            [x_l3, y_l3, z_l3] = cylinder(app.R_l);

            % Scale height of Cylinder
            z_b = z_b * app.H_b;
            z_a1 = z_a1 * app.H_a;
            z_a2 = z_a2 * app.H_a;
            z_l1 = z_l1 * app.H_l;
            z_l2 = z_l2 * app.H_l;
            z_l3 = z_l3 * app.H_l;
            % Link base and link 1 maxtrix
            Matrix_Link1 = [cos(theta1), 0, sin(theta1), 0;
                sin(theta1), 0, -cos(theta1), 0;
                0, 1, 0, app.H_l + app.H_b;
                0, 0, 0, 1];
            
            % Link link 1 and arm 1 maxtrix
            Matrix_Link2 = [cos(theta2), -sin(theta2), 0, app.H_l*cos(theta2);
                sin(theta2), cos(theta2), 0, app.H_l*sin(theta2);
                0, 0, 1, 0;
                0, 0, 0, 1];
            
            % Link link 2 and arm 2 maxtrix
            Matrix_Link3 = [cos(theta3), -sin(theta3), 0, app.H_l*cos(theta3);
                sin(theta3), cos(theta3), 0, app.H_l*sin(theta3);
                0, 0, 1, 0;
                0, 0, 0, 1];
            rotY = [cos(pi/2),  0, sin(pi/2), 0;
            0,          1, 0,         0;
            -sin(pi/2), 0, cos(pi/2), 0;
            0,          0, 0,         1];

          % Link base and link 1
        for j = 1:length(x_l1)
            for i = 1:2
                Pos1 = [x_l1(i, j); y_l1(i, j); z_l1(i, j) + app.H_b; 1];
                Pos2 = Pos1;
                x_l1(i, j) = Pos2(1);
                y_l1(i, j) = Pos2(2);
                z_l1(i, j) = Pos2(3);
            end
        end
        
        % Link link 1 and arm 1
        for j = 1:length(x_a1)
            for i = 1:2
                Pos1 = [x_a1(i, j); y_a1(i, j); z_a1(i, j) - app.H_a/2; 1];
                Pos2 = Matrix_Link1 * Pos1;
                x_a1(i, j) = Pos2(1);
                y_a1(i, j) = Pos2(2);
                z_a1(i, j) = Pos2(3);
            end
        end
        
        % Link arm 1 and link 2
        for j = 1:length(x_l2)
            for i = 1:2
                Pos1 = [x_l2(i, j); y_l2(i, j); z_l2(i, j) - app.H_l; 1];
                Pos2 = Matrix_Link1 * Matrix_Link2 * rotY * Pos1;
                x_l2(i, j) = Pos2(1);
                y_l2(i, j) = Pos2(2);
                z_l2(i, j) = Pos2(3);
            end
        end
        
        % Link link 2 and arm 2
        for j = 1:length(x_a2)
            for i = 1:2
                Pos1 = [x_a2(i, j); y_a2(i, j); z_a2(i, j) - app.H_a/2; 1];
                Pos2 = Matrix_Link1 * Matrix_Link2  * Pos1;
                x_a2(i, j) = Pos2(1);
                y_a2(i, j) = Pos2(2);
                z_a2(i, j) = Pos2(3);
            end
        end

        % Link arm 2 and link 3
        for j = 1:length(x_l3)
            for i = 1:2
                Pos1 = [x_l3(i, j); y_l3(i, j); z_l3(i, j) - app.H_l; 1];
                Pos2 = Matrix_Link1 * Matrix_Link2 * Matrix_Link3 * rotY * Pos1;
                x_l3(i, j) = Pos2(1);
                y_l3(i, j) = Pos2(2);
                z_l3(i, j) = Pos2(3);
            end
        end

        % Plot the robot in app UIAxes
        cla(app.UIAxes);  % Clear previous plot
        hold(app.UIAxes, 'on');
        grid(app.UIAxes, 'on');
%         axis(app.UIAxes, 'equal');
        xlabel(app.UIAxes, 'X');
        ylabel(app.UIAxes, 'Y');
        zlabel(app.UIAxes, 'Z');
        title(app.UIAxes, 'HoangLee Visualization');
        
        % Draw
        % Base
        surf(app.UIAxes, x_b, y_b, z_b, 'FaceColor', 'red', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_b(1,:), y_b(1,:), z_b(1,:), 'r');
        fill3(app.UIAxes,x_b(2,:), y_b(2,:), z_b(2,:), 'r');
        
        % Link 1
        surf(app.UIAxes, x_l1, y_l1, z_l1, 'FaceColor', 'blue', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_l1(1,:), y_l1(1,:), z_l1(1,:), 'b');
        fill3(app.UIAxes,x_l1(2,:), y_l1(2,:), z_l1(2,:), 'b');
        
        % Arm 1
        surf(app.UIAxes, x_a1, y_a1, z_a1, 'FaceColor', 'green', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_a1(1,:), y_a1(1,:), z_a1(1,:), 'g');
        fill3(app.UIAxes,x_a1(2,:), y_a1(2,:), z_a1(2,:), 'g');
        
        % Link 2
        surf(app.UIAxes, x_l2, y_l2, z_l2, 'FaceColor', 'yellow', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_l2(1,:), y_l2(1,:), z_l2(1,:), 'y');
        fill3(app.UIAxes,x_l2(2,:), y_l2(2,:), z_l2(2,:), 'y');
        
        % Arm 2
        surf(app.UIAxes, x_a2, y_a2, z_a2, 'FaceColor', 'cyan', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_a2(1,:), y_a2(1,:), z_a2(1,:), 'c');
        fill3(app.UIAxes,x_a2(2,:), y_a2(2,:), z_a2(2,:), 'c');
        
        % Link 3
        surf(app.UIAxes, x_l3, y_l3, z_l3, 'FaceColor', 'magenta', 'EdgeColor', 'none');
        fill3(app.UIAxes,x_l3(1,:), y_l3(1,:), z_l3(1,:), 'm');
        fill3(app.UIAxes,x_l3(2,:), y_l3(2,:), z_l3(2,:), 'm');
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
            value_theta1 = app.Theta1Slider.Value;
            app.theta1 = deg2rad(value_theta1);
            app.theta1TextArea.Value = num2str(value_theta1);
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
            value_theta2 = app.Theta2Slider.Value;
            app.theta2 = deg2rad(value_theta2);
            app.theta2TextArea.Value = num2str(value_theta2);
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
            value_theta3 = app.Theta3Slider.Value;
            app.theta3 = deg2rad(value_theta3);
            app.theta3TextArea.Value = num2str(value_theta3);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.pause_status = false;
            app.UpdateAndVizualize();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pause_status = true;
        end

        % Value changed function: theta1TextArea
        function theta1TextAreaValueChanged(app, event)
            value = str2double(app.theta1TextArea.Value);
            app.theta1 = deg2rad(value);
            app.Theta1Slider.Value = value;
        end

        % Value changed function: theta2TextArea
        function theta2TextAreaValueChanged(app, event)
            value = str2double(app.theta2TextArea.Value);
            app.theta2 = deg2rad(value);
            app.Theta2Slider.Value = value;
        end

        % Value changed function: theta3TextArea
        function theta3TextAreaValueChanged(app, event)
            value = str2double(app.theta3TextArea.Value);
            app.theta3 = deg2rad(value);
            app.Theta3Slider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-50 50];
            app.UIAxes.YLim = [-50 50];
            app.UIAxes.ZLim = [0 100];
            app.UIAxes.XTick = [-50 0 50];
            app.UIAxes.XTickLabel = {'-50'; '0'; '50'};
            app.UIAxes.ZTick = [0 50 100];
            app.UIAxes.ZTickLabel = {'0'; '50'; '100'};
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.theta1TextAreaLabel = uilabel(app.UIFigure);
            app.theta1TextAreaLabel.Text = 'theta1';
            app.theta1TextArea = uitextarea(app.UIFigure);
            app.theta1TextArea.ValueChangedFcn = createCallbackFcn(app, @theta1TextAreaValueChanged, true);
            app.theta2TextAreaLabel = uilabel(app.UIFigure);
            app.theta2TextAreaLabel.Text = 'theta2';
            app.theta2TextArea = uitextarea(app.UIFigure);
            app.theta2TextArea.ValueChangedFcn = createCallbackFcn(app, @theta2TextAreaValueChanged, true);
            app.theta3TextAreaLabel = uilabel(app.UIFigure);
            app.theta3TextAreaLabel.Text = 'theta3';
            app.theta3TextArea = uitextarea(app.UIFigure);
            app.theta3TextArea.ValueChangedFcn = createCallbackFcn(app, @theta3TextAreaValueChanged, true);
            app.PositionofEndEffectorLabel = uilabel(app.UIFigure);
            app.PositionofEndEffectorLabel.Text = 'Position of End Effector';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X';
            app.XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y';
            app.YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z';
            app.ZTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = articulated_arm_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end